classdef Moco_trachpc < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        ArduinoPanel              matlab.ui.container.Panel
        RewardPanel               matlab.ui.container.Panel
        ManualReward_lbl          matlab.ui.control.Label
        EarnedReward_lbl          matlab.ui.control.Label
        ManualReward_count        matlab.ui.control.Label
        RewardCountSign_lbl       matlab.ui.control.Label
        ManualReward_ul           matlab.ui.control.Label
        RewardMicroLiterSign_lbl  matlab.ui.control.Label
        EarnedReward_count        matlab.ui.control.Label
        EarnedReward_ul           matlab.ui.control.Label
        ManualTrigger_btn         matlab.ui.control.Button
        ulPerReward_lbl           matlab.ui.control.Label
        ulPerReward_editFld       matlab.ui.control.NumericEditField
        TotalReward_lbl           matlab.ui.control.Label
        totalReward_count         matlab.ui.control.Label
        totalReward_ul            matlab.ui.control.Label
        PulseWidthmsLabel         matlab.ui.control.Label
        PulseWidth_spnr           matlab.ui.control.Spinner
        ReadLicksLabel            matlab.ui.control.Label
        SensorSwitch_swtch        matlab.ui.control.Switch
        ArduinoCall_swtch         matlab.ui.control.Switch
        ArduinoLabel              matlab.ui.control.Label
        StimulusPanel             matlab.ui.container.Panel
        StimProgram_swtch         matlab.ui.control.Switch
        ViewsListBoxLabel         matlab.ui.control.Label
        StimViews_list            matlab.ui.control.ListBox
        CoherenceLabel            matlab.ui.control.Label
        SetCoherence_spnr         matlab.ui.control.Spinner
        LifeTimeframesLabel       matlab.ui.control.Label
        SetLifeTime_spnr          matlab.ui.control.Spinner
        SetRandomDirection_btn    matlab.ui.control.Button
        NotificationsPanel        matlab.ui.container.Panel
        NotificationsLabel        matlab.ui.control.Label
        NotificationCenter        matlab.ui.control.Label
        WheelPanel                matlab.ui.container.Panel
        WheelCall_swtch           matlab.ui.control.Switch
        TurnThresholddegsecLabel  matlab.ui.control.Label
        WheelTurnThresh_spnr      matlab.ui.control.Spinner
        ReadPositionLabel         matlab.ui.control.Label
        WheelRead_swtch           matlab.ui.control.Switch
        WheelAxis                 matlab.ui.control.UIAxes
    end


    properties (Access = public)
        ard    = Arduino;       % Arduino/lickport
        stmCmd = StimCommander; % Stimulus Commander
        notif  = Notifications;
        whl    = Wheel;
    end

    methods (Access = private)   
     
        %% Mark: UPDATE REWARD AMOUNT TABLE
        function updateEarnedRewardValues(app)        
            app.EarnedReward_count.Text = num2str(str2double(app.EarnedReward_count.Text) + 1);
            app.EarnedReward_ul.Text    =  app.updateMicroltrAmount(app.EarnedReward_count.Text);
            app.updateTotalRewardValues;
        end
        
        function updateManualRewardValues(app)
            app.ManualReward_count.Text = num2str(str2double(app.ManualReward_count.Text) + 1);
            app.ManualReward_ul.Text    = app.updateMicroltrAmount(app.ManualReward_count.Text);
            app.updateTotalRewardValues;     
        end
        
        function updateTotalRewardValues(app)
            app.totalReward_count.Text  = num2str(str2double(app.totalReward_count.Text) + 1);
            app.totalReward_ul.Text     = app.updateMicroltrAmount(app.totalReward_count.Text);
        end    
        
        function rewardAmountInMicroLtrs_string = updateMicroltrAmount(app, rewardCountTextString)
            rewardAmountInMicroLtrs_string      = num2str(str2double(rewardCountTextString) * app.ulPerReward_editFld.Value);
        end
        
        % Mark: Update Notification Center Text
        function updateNotificationCenter(app, fontColor, tString)
            app.NotificationCenter.Text      = tString;
            app.NotificationCenter.FontColor = fontColor;  
        end
 
        % Mark: Update Lifetime and Coherence Spinners (and other TBD parameters)        
        function updateMocoParameterVals(app, parameter)
            switch parameter
                case 'coherence'
                    newValue    = num2str(app.SetCoherence_spnr.Value/100)
                    setMessage  = StimMessages.set_coherence;
                    getMessage  = StimMessages.get_coherence; 
                case 'lifetime'
                    newValue    = num2str(app.SetLifeTime_spnr.Value);
                    setMessage  = StimMessages.set_lifeTime;
                    getMessage  = StimMessages.get_lifeTime;   
            end
 
            app.stmCmd.write(setMessage);
            pause(.05);
            app.stmCmd.write(newValue); 
            pause(.05);            
            app.stmCmd.write(getMessage);
            retreivedValue = str2double(app.stmCmd.waitForMessage(2,false))    
            
            switch parameter
                case 'coherence'
                    if isnan(retreivedValue) 
                        app.SetCoherence_spnr.Value = 0;
                        app.SetCoherence_spnr.FontColor = [.7 0 0];                        
                    else
                        app.SetCoherence_spnr.Value = retreivedValue;
                        app.SetCoherence_spnr.FontColor = [0 0 0];                        
                    end                        
                case 'lifetime'
                    if isnan(retreivedValue) 
                        app.SetLifeTime_spnr.Value = 1;
                        app.SetLifeTime_spnr.FontColor = [0 .7 0];                        
                    else
                        app.SetLifeTime_spnr.Value = retreivedValue;
                        app.SetLifeTime_spnr.FontColor = [0 0 0];  
                    end
            end
            
            if isnan(retreivedValue)                     
                app.updateNotificationCenter([0 .75 0], sprintf('Could not update %s', parameter));                                    
            else                      
                app.updateNotificationCenter([0 0 0], sprintf('%s successefully changed', parameter));                 
            end               
           
            app.stmCmd.write(app.StimViews_list.Value)             
        end
        
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            disp('DELETING AVAILABLE INSTRUMENTS')
            delete(instrfind('tag', Devices.arduinoNameTag));
            delete(instrfind('tag', Devices.wheelNameTag));
        end

        % Value changed function: ArduinoCall_swtch
        function press_ArduinoCall_swtch(app, event)
            
            if strcmp(app.ArduinoCall_swtch.Value,'On')
                app.updateNotificationCenter([0 0 0], 'Calling Arduino');
                disableArduinoButtons(app)                
                drawnow; 
                pause(.25);
                app.ard.callArduino; % calls the arduino;    
            
                if strcmp(app.ard.Mod.Status, 'open')
                    app.updateNotificationCenter([0 .75 .05], 'Arduino connected - armed and ready :)');
                    app.PulseWidth_spnr.Value       = app.ard.readPulseWidth;  
                    
                elseif strcmp(app.ard.Mod.Status, 'closed')
                    app.updateNotificationCenter([1 .75 .5], {'Arduino NOT connected';'Can use keyboard or try to call again'});
                    app.ArduinoCall_swtch.Value = 'Off';                    
                end   
                
                app.PulseWidth_spnr.Enable       = 'On';            
                app.PulseWidth_spnr.Editable     = 'Off';       
                app.SensorSwitch_swtch.Enable    = 'On';
                app.ManualTrigger_btn.Enable     = 'On';            
                app.SensorSwitch_swtch.Value     = 'On';
                app.press_SensingLicks_swtch;       
            
            end
            
            if app.isvalid && strcmp(app.ArduinoCall_swtch.Value,'Off')
                disableArduinoButtons(app)
                app.updateNotificationCenter([1 .75 .5], {'Arduino Disabled'});
            end
            
            function disableArduinoButtons(app)
                app.PulseWidth_spnr.Enable       = 'Off';            
                app.PulseWidth_spnr.Editable     = 'Off';       
                app.SensorSwitch_swtch.Enable    = 'Off';
                app.ManualTrigger_btn.Enable     = 'Off';            
                app.SensorSwitch_swtch.Value     = 'Off'; 
            end


        end

        % Value changed function: SetLifeTime_spnr
        function press_SetLifeTime_spnr(app, event)
            app.updateMocoParameterVals('lifetime')
        end

        % Value changed function: ulPerReward_editFld
        function change_ulPerReward(app, event)
            app.EarnedReward_ul.Text   = app.updateMicroltrAmount(app.EarnedReward_count.Text);
            app.ManualReward_ul.Text   = app.updateMicroltrAmount(app.ManualReward_count.Text);
            app.totalReward_ul.Text    = app.updateMicroltrAmount(app.totalReward_count.Text);
        end

        % Value changed function: StimViews_list
        function click_StimViews_list(app, event)
            value = app.StimViews_list.Value;
            app.stmCmd.write(value)
        end

        % Close request function: UIFigure
        function closeRequest_UIFigure(app, event)
            app.SensorSwitch_swtch.Value = 'Off';
            app.press_SensingLicks_swtch;
            delete(app);
        end

        % Button pushed function: ManualTrigger_btn
        function press_ManualTrigger_btn(app, event)
            app.ard.triggerValve;
            app.updateManualRewardValues;
        end

        % Value changed function: PulseWidth_spnr
        function press_PulseWidth_spnr(app, event)
            value = app.PulseWidth_spnr.Value;
            app.ard.setPulseWidth(value);
            app.PulseWidth_spnr.Value       = app.ard.readPulseWidth;                      
        end

        % Value changed function: SensorSwitch_swtch
        function press_SensingLicks_swtch(app, event)
     
            while app.isvalid && strcmp(app.SensorSwitch_swtch.Value, 'On')  
                if strcmp(app.ard.Mod.Status, 'open') 
                    if app.ard.readSensorState == 1
                        app.ard.triggerValve;  
                        app.updateEarnedRewardValues;
                    end
                elseif strcmp(readKey, 'l')
                    app.ard.triggerValve;  
                    app.updateEarnedRewardValues;
                end
                pause(app.ard.readRate)
            end        
            
        end

        % Value changed function: SetCoherence_spnr
        function press_SetCoherence_spnr(app, event)
            app.updateMocoParameterVals('coherence')
        end

        % Button pushed function: SetRandomDirection_btn
        function press_SetRandomDirection_btn(app, event)
            app.stmCmd.write(StimMessages.set_randDotDirection);
            app.stmCmd.write(app.StimViews_list.Value)
        end

        % Value changed function: StimProgram_swtch
        function press_StimProgram_swtch(app, event)
            value = app.StimProgram_swtch.Value;
            
            if strcmp(value, 'On') 
                app.updateNotificationCenter([0 0 0], 'Opening stimulus program in new matlab...');                                    
                app.stmCmd.openCommander;
                stmPrgMsg = app.stmCmd.waitForMessage(20,false);
                if strcmp(stmPrgMsg, StimMessages.connectionConfirmation)
                    app.updateNotificationCenter([0 .7 0], 'Stim Program ready to receive messages'); 
                    
                    % Setup random signal direction button
                    app.SetRandomDirection_btn.Enable = 'On';
                    
                    % Setup View list                    
                    app.StimViews_list.Enable         = 'On';                    
                    app.StimViews_list.Items          = {StimMessages.view_waitScreen,...
                                                         StimMessages.view_dotMovie,...
                                                         StimMessages.view_grayScreen,...
                                                         StimMessages.view_whiteScreen};
                    % Setup Coherence Spinner
                    app.updateMocoParameterVals('coherence')
                    app.SetCoherence_spnr.Enable = 'On';
                                
                    % Setup LifeTime Spinner
                    app.updateMocoParameterVals('lifetime')
                    app.SetLifeTime_spnr.Enable = 'On';
                    
                else
                    app.updateNotificationCenter([.7 0 0], 'Could not open stim program. No Confirmation received. Try Again');
                    disableStimButtons(app)                    
                end
            end
            
            if strcmp(value, 'Off')
                app.stmCmd.write(StimMessages.shutDown);
                disableStimButtons(app)
                app.updateNotificationCenter([0 .7 0], 'Stim Program shut down');                
            end
            
            function disableStimButtons(app)
                app.updateNotificationCenter([.7 0 0], 'Could not open stim program. No Confirmation received. Try Again');
                app.StimProgram_swtch.Value = 'Off';                
                app.StimViews_list.Enable = 'Off';
                app.SetCoherence_spnr.Enable = 'Off';
                app.SetLifeTime_spnr.Enable = 'Off';
                app.SetRandomDirection_btn.Enable = 'off';
                drawnow;                
            end


        end

        % Value changed function: WheelCall_swtch
        function press_WheelCall_swtch(app, event)
            value = app.WheelCall_swtch.Value;
            if strcmp(value, 'On') 
                app.updateNotificationCenter([0 0 0], 'Calling wheel...');                                    
                app.whl.callWheel;    
                if strcmp(app.whl.connectionStatus, 'Open')
                    app.WheelRead_swtch.Enable = 'On';                                
                    app.WheelRead_swtch.Value = 'On';
                    app.press_WheelRead_swtch;
                    app.updateNotificationCenter([0 .7 0], 'Wheel Connected :)');  
                    
                else
                    app.updateNotificationCenter([.7 0 0], 'Could not contact Wheel. Use Key Board or try Again');
                    disableWheelButtons(app)                    
                end
                
            end
            
            if strcmp(value, 'Off')
                disableWheelButtons(app)
                app.updateNotificationCenter([.7 0 0], 'Wheel is Off');
            end
            
            function disableWheelButtons(app)
                app.WheelRead_swtch.Enable = 'Off';                
                app.WheelRead_swtch.Value = 'Off';
                app.press_WheelRead_swtch;      
            end            
        end

        % Value changed function: WheelRead_swtch
        function press_WheelRead_swtch(app, event)
            if app.isvalid && strcmp(app.WheelRead_swtch.Value, 'On') 
                plot(app.WheelAxis, nan, 0, 'c.', 'markersize', 30);
                cPos = 0;
            end 
            
            if app.isvalid && strcmp(app.WheelRead_swtch.Value, 'Off')
                plot(app.WheelAxis, nan, nan, 'c.', 'markersize', 30);
                text(app.WheelAxis, .5, .5, 'NOT READING WHEEL', 'units', 'normalized', 'horizontalalignment', 'center') 
            end
            
            while app.isvalid && strcmp(app.WheelRead_swtch.Value, 'On')  
                cmd = readKey;
                if strcmp(app.whl.connectionStatus, 'Open')
                   cPos = app.whl.readWheelPosition;
                elseif strcmp(cmd, 'a')
                    cPos = cPos - 1; 
                elseif strcmp(cmd, 'd')
                    cPos = cPos + 1;
                end
                app.WheelAxis.Children.XData = cPos;
                drawnow;                
                pause(Devices.wheelReadInterval);
            end


        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @closeRequest_UIFigure, true);
            app.ArduinoPanel = uipanel(app.UIFigure);
            app.ArduinoPanel.Title = 'Arduino';
            app.RewardPanel = uipanel(app.ArduinoPanel);
            app.RewardPanel.Title = 'Reward';
            app.ManualReward_lbl = uilabel(app.RewardPanel);
            app.ManualReward_lbl.Text = 'Manual';
            app.EarnedReward_lbl = uilabel(app.RewardPanel);
            app.EarnedReward_lbl.Text = 'Earned';
            app.ManualReward_count = uilabel(app.RewardPanel);
            app.ManualReward_count.Text = '0';
            app.RewardCountSign_lbl = uilabel(app.RewardPanel);
            app.RewardCountSign_lbl.Text = '#';
            app.ManualReward_ul = uilabel(app.RewardPanel);
            app.ManualReward_ul.Text = '0';
            app.RewardMicroLiterSign_lbl = uilabel(app.RewardPanel);
            app.RewardMicroLiterSign_lbl.Text = 'ul';
            app.EarnedReward_count = uilabel(app.RewardPanel);
            app.EarnedReward_count.Text = '0';
            app.EarnedReward_ul = uilabel(app.RewardPanel);
            app.EarnedReward_ul.Text = '0';
            app.ManualTrigger_btn = uibutton(app.RewardPanel, 'push');
            app.ManualTrigger_btn.ButtonPushedFcn = createCallbackFcn(app, @press_ManualTrigger_btn, true);
            app.ManualTrigger_btn.Enable = 'off';
            app.ManualTrigger_btn.Text = 'Trigger';
            app.ulPerReward_lbl = uilabel(app.RewardPanel);
            app.ulPerReward_lbl.Text = 'ul/reward';
            app.ulPerReward_editFld = uieditfield(app.RewardPanel, 'numeric');
            app.ulPerReward_editFld.ValueChangedFcn = createCallbackFcn(app, @change_ulPerReward, true);
            app.ulPerReward_editFld.Limits = [0 Inf];
            app.ulPerReward_editFld.Value = 2;
            app.TotalReward_lbl = uilabel(app.RewardPanel);
            app.TotalReward_lbl.Text = 'Total';
            app.totalReward_count = uilabel(app.RewardPanel);
            app.totalReward_count.Text = '0';
            app.totalReward_ul = uilabel(app.RewardPanel);
            app.totalReward_ul.Text = '0';
            app.PulseWidthmsLabel = uilabel(app.RewardPanel);
            app.PulseWidthmsLabel.Text = 'Pulse Width (ms)';
            app.PulseWidth_spnr = uispinner(app.RewardPanel);
            app.PulseWidth_spnr.Step = 5;
            app.PulseWidth_spnr.ValueChangedFcn = createCallbackFcn(app, @press_PulseWidth_spnr, true);
            app.PulseWidth_spnr.Limits = [0 Inf];
            app.PulseWidth_spnr.RoundFractionalValues = 'on';
            app.PulseWidth_spnr.ValueDisplayFormat = '%.0f';
            app.PulseWidth_spnr.Editable = 'off';
            app.PulseWidth_spnr.Enable = 'off';
            app.ReadLicksLabel = uilabel(app.ArduinoPanel);
            app.ReadLicksLabel.Text = 'Read Licks';
            app.SensorSwitch_swtch = uiswitch(app.ArduinoPanel, 'slider');
            app.SensorSwitch_swtch.ValueChangedFcn = createCallbackFcn(app, @press_SensingLicks_swtch, true);
            app.SensorSwitch_swtch.Enable = 'off';
            app.ArduinoCall_swtch = uiswitch(app.ArduinoPanel, 'slider');
            app.ArduinoCall_swtch.ValueChangedFcn = createCallbackFcn(app, @press_ArduinoCall_swtch, true);
            app.ArduinoLabel = uilabel(app.ArduinoPanel);
            app.ArduinoLabel.Text = 'Arduino';
            app.StimulusPanel = uipanel(app.UIFigure);
            app.StimulusPanel.Title = 'Stimulus';
            app.StimProgram_swtch = uiswitch(app.StimulusPanel, 'slider');
            app.StimProgram_swtch.ValueChangedFcn = createCallbackFcn(app, @press_StimProgram_swtch, true);
            app.ViewsListBoxLabel = uilabel(app.StimulusPanel);
            app.ViewsListBoxLabel.Text = 'Views';
            app.StimViews_list = uilistbox(app.StimulusPanel);
            app.StimViews_list.Items = {'WaitScreen', 'Moco', 'WhiteScreen', 'GrayScreen'};
            app.StimViews_list.ValueChangedFcn = createCallbackFcn(app, @click_StimViews_list, true);
            app.StimViews_list.Enable = 'off';
            app.StimViews_list.Value = 'WaitScreen';
            app.CoherenceLabel = uilabel(app.StimulusPanel);
            app.CoherenceLabel.Text = 'Coherence (%)';
            app.SetCoherence_spnr = uispinner(app.StimulusPanel);
            app.SetCoherence_spnr.ValueChangedFcn = createCallbackFcn(app, @press_SetCoherence_spnr, true);
            app.SetCoherence_spnr.Limits = [0 100];
            app.SetCoherence_spnr.RoundFractionalValues = 'on';
            app.SetCoherence_spnr.ValueDisplayFormat = '%.0f';
            app.SetCoherence_spnr.Enable = 'off';
            app.SetCoherence_spnr.Value = 60;
            app.LifeTimeframesLabel = uilabel(app.StimulusPanel);
            app.LifeTimeframesLabel.Text = 'Life Time (frames)';
            app.SetLifeTime_spnr = uispinner(app.StimulusPanel);
            app.SetLifeTime_spnr.Step = 5;
            app.SetLifeTime_spnr.ValueChangedFcn = createCallbackFcn(app, @press_SetLifeTime_spnr, true);
            app.SetLifeTime_spnr.Limits = [0 Inf];
            app.SetLifeTime_spnr.ValueDisplayFormat = '%.0f';
            app.SetLifeTime_spnr.Enable = 'off';
            app.SetLifeTime_spnr.Value = 120;
            app.SetRandomDirection_btn = uibutton(app.StimulusPanel, 'push');
            app.SetRandomDirection_btn.ButtonPushedFcn = createCallbackFcn(app, @press_SetRandomDirection_btn, true);
            app.SetRandomDirection_btn.Enable = 'off';
            app.SetRandomDirection_btn.Text = 'Random Direction';
            app.NotificationsPanel = uipanel(app.UIFigure);
            app.NotificationsLabel = uilabel(app.NotificationsPanel);
            app.NotificationsLabel.Text = 'Notifications:';
            app.NotificationCenter = uilabel(app.NotificationsPanel);
            app.NotificationCenter.Text = '';
            app.WheelPanel = uipanel(app.UIFigure);
            app.WheelPanel.Title = 'Wheel';
            app.WheelCall_swtch = uiswitch(app.WheelPanel, 'slider');
            app.WheelCall_swtch.ValueChangedFcn = createCallbackFcn(app, @press_WheelCall_swtch, true);
            app.TurnThresholddegsecLabel = uilabel(app.WheelPanel);
            app.TurnThresholddegsecLabel.Text = 'Turn Threshold (deg/sec)';
            app.WheelTurnThresh_spnr = uispinner(app.WheelPanel);
            app.WheelTurnThresh_spnr.Limits = [0 100];
            app.WheelTurnThresh_spnr.RoundFractionalValues = 'on';
            app.WheelTurnThresh_spnr.ValueDisplayFormat = '%.0f';
            app.WheelTurnThresh_spnr.Value = 60;
            app.ReadPositionLabel = uilabel(app.WheelPanel);
            app.ReadPositionLabel.Enable = 'off';
            app.ReadPositionLabel.Text = 'Read Position';
            app.WheelRead_swtch = uiswitch(app.WheelPanel, 'slider');
            app.WheelRead_swtch.ValueChangedFcn = createCallbackFcn(app, @press_WheelRead_swtch, true);
            app.WheelRead_swtch.Enable = 'off';
            app.WheelAxis = uiaxes(app.UIFigure);
            xlabel(app.WheelAxis, 'Wheel Position')
            app.WheelAxis.XLim = [-180 180];
            app.WheelAxis.XLimMode = 'manual';
            app.WheelAxis.YLim = [-1 1];
            app.WheelAxis.YLimMode = 'manual';
            app.WheelAxis.YTickLabel = '';
        end
    end

    methods (Access = public)

        % Construct app
        function app = Moco_trachpc

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end